classdef app_cimishujuchuli < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        appUIFigure      matlab.ui.Figure
        Menu             matlab.ui.container.Menu
        quitMenu         matlab.ui.container.Menu
        helpMenu         matlab.ui.container.Menu
        authorrightMenu  matlab.ui.container.Menu
        TextArea         matlab.ui.control.TextArea
        Panel            matlab.ui.container.Panel
        XEditFieldLabel  matlab.ui.control.Label
        XEditField       matlab.ui.control.EditField
        Button           matlab.ui.control.Button
        YEditFieldLabel  matlab.ui.control.Label
        YEditField       matlab.ui.control.EditField
        Button_2         matlab.ui.control.Button
        ZEditFieldLabel  matlab.ui.control.Label
        ZEditField       matlab.ui.control.EditField
        Button_3         matlab.ui.control.Button
        UIAxes           matlab.ui.control.UIAxes
        Button_4         matlab.ui.control.Button
        Button_5         matlab.ui.control.Button
        Button_6         matlab.ui.control.Button
        UITable          matlab.ui.control.Table
        UITable_2        matlab.ui.control.Table
        UITable_3        matlab.ui.control.Table
        Slider           matlab.ui.control.Slider
        Label            matlab.ui.control.Label
        Panel_2          matlab.ui.container.Panel
        Label_2          matlab.ui.control.Label
        UIAxes2          matlab.ui.control.UIAxes
        UIAxes2_2        matlab.ui.control.UIAxes
        Button_7         matlab.ui.control.Button
        Button_8         matlab.ui.control.Button
        Button_9         matlab.ui.control.Button
    end

    %%%%函数文本更新的代码模块
    properties (Access = private)
        StrArray % Description
    end
    
    methods (Access = private)
        function Refresh_func(app,StrArrayNew)
            if length(app.StrArray)>=30
                app.StrArray={};
            end
            app.StrArray=[app.StrArray,StrArrayNew];
            app.TextArea.Value=app.StrArray;
        end
        
    end

    methods (Access = private)

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            %%%X方向磁密数据浏览路径按键的代码选择
            [filename,pathname]=uigetfile({'*.xlsx';'*.csv'});
            if ~isequal(filename,0)
                app.XEditField.Value=fullfile(pathname,filename);
                StrArrayNew={'massege:X方向磁密数据文件浏览完毕'}
                Refresh_func(app,StrArrayNew);
            else
                StrArrayNew={'massege:X方向磁密数据文件浏览失败'}
                Refresh_func(app,StrArrayNew);
            end
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            %%%Y方向磁密数据浏览路径按键的代码选择
            [filename,pathname]=uigetfile({'*.xlsx';'*.csv'});
            if ~isequal(filename,0)
                app.YEditField.Value=fullfile(pathname,filename);
                StrArrayNew={'massege:Y方向磁密数据文件浏览完毕'}
                Refresh_func(app,StrArrayNew);
            else
                StrArrayNew={'massege:Y方向磁密数据文件浏览失败'}
                Refresh_func(app,StrArrayNew);
            end
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            %%%Z方向磁密数据浏览路径按键的代码选择
            [filename,pathname]=uigetfile({'*.xlsx';'*.csv'});
            if ~isequal(filename,0)
                app.ZEditField.Value=fullfile(pathname,filename);
                StrArrayNew={'massege:Z方向磁密数据文件浏览完毕'}
                Refresh_func(app,StrArrayNew);
            else
                StrArrayNew={'massege:Z方向磁密数据文件浏览失败'}
                Refresh_func(app,StrArrayNew);
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %%%%%X方向磁密数据加载按钮确定选择的代码模块
            lujin=app.XEditField.Value;
            t=readtable(lujin);
            app.UITable.Data=t;
            app.UITable.ColumnName=t.Properties.VariableNames;
            shuju=table2array(app.UITable.Data);
            xl=app.UITable.ColumnName{1};
            yl=app.UITable.ColumnName{2};
            cla(app.UIAxes)
            StrArrayNew={'massege:图像区已清空'};
            Refresh_func(app,StrArrayNew);
            N=length(shuju(:,1));
            v=app.Slider.Value;
            for i=1:N
                scatter3(app.UIAxes,shuju(i,1),0,shuju(i,2),15,'r','filled');
                hold(app.UIAxes,"on")
                pause(0.0001/v)
                drawnow
            end
            hold(app.UIAxes,"off")
            %             legend(app.UIAxes,'X方向的磁密曲线')
            %             hold(app.UIAxes,"on")
            StrArrayNew={'massege:X方向的磁密数据已在表格显示'};
            Refresh_func(app,StrArrayNew);
            StrArrayNew={'massege:X方向的磁密数据已在图像显示'};
            Refresh_func(app,StrArrayNew);
            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            %%%%%Y方向磁密数据加载按钮确定选择的代码模块
            lujin=app.YEditField.Value;
            t=readtable(lujin);
            app.UITable_2.Data=t;
            app.UITable_2.ColumnName=t.Properties.VariableNames;
            shuju=table2array(app.UITable.Data);
            xl=app.UITable_2.ColumnName{1};
            yl=app.UITable_2.ColumnName{2};
            cla(app.UIAxes)
            StrArrayNew={'massege:图像区已清空'};
            Refresh_func(app,StrArrayNew);
            N=length(shuju(:,1));
            v=app.Slider.Value;
            for i=1:N
                scatter3(app.UIAxes,0,shuju(i,1),shuju(i,2),15,'b','filled');
                hold(app.UIAxes,"on")
                pause(0.0001/v)
                drawnow
            end
            hold(app.UIAxes,"off")
            %             legend(app.UIAxes,'Y方向的磁密曲线')
            %             hold(app.UIAxes,"on")
            StrArrayNew={'massege:Y方向的磁密数据已在表格显示'};
            Refresh_func(app,StrArrayNew);
            StrArrayNew={'massege:Y方向的磁密数据已在图像显示'};
            Refresh_func(app,StrArrayNew);
            
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            %%%%%Z方向磁密数据加载按钮确定选择的代码模块
            lujin=app.ZEditField.Value;
            t=readtable(lujin);
            app.UITable_3.Data=t;
            app.UITable_3.ColumnName=t.Properties.VariableNames;
            shuju=table2array(app.UITable.Data);
            xl=app.UITable_3.ColumnName{1};
            yl=app.UITable_3.ColumnName{2};
            cla(app.UIAxes)
            StrArrayNew={'massege:图像区已清空'};
            Refresh_func(app,StrArrayNew);
            N=length(shuju(:,1));
            v=app.Slider.Value;
            for i=1:N
                scatter3(app.UIAxes,0,shuju(i,2),shuju(i,1),15,'g','filled');
                hold(app.UIAxes,"on")
                pause(0.0001/v)
                drawnow
            end
            hold(app.UIAxes,"off")
            %             legend(app.UIAxes,'Z方向的磁密曲线')
            %             hold(app.UIAxes,"off")
            StrArrayNew={'massege:Z方向的磁密数据已在表格显示'};
            Refresh_func(app,StrArrayNew);
            StrArrayNew={'massege:Z方向的磁密数据已在图像显示'};
            Refresh_func(app,StrArrayNew);
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            %磁密矢量叠加按钮的代码模块
            %重新加载数据
            lujin1=app.XEditField.Value;%路径调用
            t=readtable(lujin1);
            app.UITable.Data=t;
            app.UITable.ColumnName=t.Properties.VariableNames;
            shuju_X=table2array(app.UITable.Data);
            lujin2=app.YEditField.Value;%路径调用
            t=readtable(lujin2);
            app.UITable_2.Data=t;
            app.UITable_2.ColumnName=t.Properties.VariableNames;
            shuju_Y=table2array(app.UITable_2.Data);
            lujin3=app.ZEditField.Value;%路径调用
            t=readtable(lujin3);
            app.UITable_3.Data=t;
            app.UITable_3.ColumnName=t.Properties.VariableNames;
            shuju_Z=table2array(app.UITable_3.Data);
            %叠加过程，设一个数组变量为cimidaxiao，长度为N=length(shuju)
            N=length(shuju_X(:,1));
            cimidaxiao=zeros(N,1);
            cimidaxiao=(shuju_X(:,2).^2+shuju_Y(:,2).^2+shuju_Z(:,2).^2).^0.5;
            plot(app.UIAxes2,shuju_X(:,1),cimidaxiao,'r-','Linewidth',2)
            StrArrayNew={'massege:磁密矢量合成已完成'};
            Refresh_func(app,StrArrayNew);
            StrArrayNew={'massege:磁密矢量合成结果已体现在图中'};
            Refresh_func(app,StrArrayNew);
            c=linspace(1,100,N);
            for i=1:200
                scatter3(app.UIAxes2_2,shuju_X(i,2),shuju_Y(i,2),shuju_Z(i,2),10,c(N-i),'filled');
                hold(app.UIAxes2_2,"on")
                drawnow
            end
            hold(app.UIAxes,"off")
            StrArrayNew={'massege:磁密矢量方向合成结果已体现在图中'};
            Refresh_func(app,StrArrayNew);
            
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            %导出excel文件的代码模块
            %调用的按此分析模块的部分代码
            lujin1=app.XEditField.Value;%路径调用
            t=readtable(lujin1);
            app.UITable.Data=t;
            app.UITable.ColumnName=t.Properties.VariableNames;
            shuju_X=table2array(app.UITable.Data);
            lujin2=app.YEditField.Value;%路径调用
            t=readtable(lujin2);
            app.UITable_2.Data=t;
            app.UITable_2.ColumnName=t.Properties.VariableNames;
            shuju_Y=table2array(app.UITable_2.Data);
            lujin3=app.ZEditField.Value;%路径调用
            t=readtable(lujin3);
            app.UITable_3.Data=t;
            app.UITable_3.ColumnName=t.Properties.VariableNames;
            shuju_Z=table2array(app.UITable_3.Data);
            %叠加过程，设一个数组变量为cimidaxiao，长度为N=length(shuju)
            N=length(shuju_X(:,1));
            cimidaxiao=zeros(N,1);
            cimidaxiao=(shuju_X(:,2).^2+shuju_Y(:,2).^2+shuju_Z(:,2).^2).^0.5;
            data=[shuju_X(:,1),cimidaxiao];
            filename = 'cimidaxiao.xlsx';
            xlswrite(filename,data);
            StrArrayNew={'massege:数据文档导出成功'};
            Refresh_func(app,StrArrayNew);
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            shujuchuli
        end

        % Menu selected function: quitMenu
        function quitMenuSelected(app, event)
            delete(app.appUIFigure)
        end

        % Menu selected function: authorrightMenu
        function authorrightMenuSelected(app, event)
            msgbox("由华北电力大学(保定) 2019级电气工程及其自动化 沈寒涛完成，华北电力大学(保定) 级机械工程系 徐明星和张文博士指导 ","作者信息","help")
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.appUIFigure = uifigure;
            app.appUIFigure.Name = '磁密处理app';
            app.Menu = uimenu(app.appUIFigure);
            app.Menu.Text = 'Menu';
            app.quitMenu = uimenu(app.Menu);
            app.quitMenu.MenuSelectedFcn = createCallbackFcn(app, @quitMenuSelected, true);
            app.quitMenu.Text = 'quit';
            app.helpMenu = uimenu(app.appUIFigure);
            app.helpMenu.Text = 'help';
            app.authorrightMenu = uimenu(app.helpMenu);
            app.authorrightMenu.MenuSelectedFcn = createCallbackFcn(app, @authorrightMenuSelected, true);
            app.authorrightMenu.Text = 'author right';
            app.TextArea = uitextarea(app.appUIFigure);
            app.Panel = uipanel(app.appUIFigure);
            app.Panel.Title = '文件路径选择和数据加载';
            app.XEditFieldLabel = uilabel(app.Panel);
            app.XEditFieldLabel.Text = 'X方向磁密数据文件路径';
            app.XEditField = uieditfield(app.Panel, 'text');
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '确定并加载数据';
            app.YEditFieldLabel = uilabel(app.Panel);
            app.YEditFieldLabel.Text = 'Y方向磁密数据文件路径';
            app.YEditField = uieditfield(app.Panel, 'text');
            app.Button_2 = uibutton(app.Panel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '确定并加载数据';
            app.ZEditFieldLabel = uilabel(app.Panel);
            app.ZEditFieldLabel.Text = 'Z方向磁密数据文件路径';
            app.ZEditField = uieditfield(app.Panel, 'text');
            app.Button_3 = uibutton(app.Panel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '确定并加载数据';
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, '三维展示')
            app.Button_4 = uibutton(app.Panel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '浏览路径';
            app.Button_5 = uibutton(app.Panel, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '浏览路径';
            app.Button_6 = uibutton(app.Panel, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '浏览路径';
            app.UITable = uitable(app.Panel);
            app.UITable.ColumnName = {'time'; 'X_B'};
            app.UITable.RowName = {};
            app.UITable_2 = uitable(app.Panel);
            app.UITable_2.ColumnName = {'time'; 'Y_B'};
            app.UITable_2.RowName = {};
            app.UITable_3 = uitable(app.Panel);
            app.UITable_3.ColumnName = {'time'; 'Z_B'};
            app.UITable_3.RowName = {};
            app.Slider = uislider(app.Panel);
            app.Slider.Limits = [1 10];
            app.Slider.Value = 10;
            app.Label = uilabel(app.Panel);
            app.Label.Text = '播放速度';
            app.Panel_2 = uipanel(app.appUIFigure);
            app.Panel_2.Title = '三维磁密计算模块';
            app.Label_2 = uilabel(app.Panel_2);
            app.Label_2.Text = '利用三个方向上的磁密数据进行矢量叠加，进而得出测量点上的磁密大小及其方向。';
            app.UIAxes2 = uiaxes(app.Panel_2);
            title(app.UIAxes2, '磁密分析一：大小分析')
            app.UIAxes2_2 = uiaxes(app.Panel_2);
            title(app.UIAxes2_2, '磁密分析二：方向分析')
            app.Button_7 = uibutton(app.Panel_2, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '按此开始';
            app.Button_8 = uibutton(app.Panel_2, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '保存数据';
            app.Button_9 = uibutton(app.Panel_2, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '进一步分析';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app_cimishujuchuli

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.appUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.appUIFigure)
        end
    end
end