classdef pinghuachuli < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Label               matlab.ui.control.Label
        EditField           matlab.ui.control.EditField
        Button              matlab.ui.control.Button
        Button_2            matlab.ui.control.Button
        DropDownLabel       matlab.ui.control.Label
        DropDown            matlab.ui.control.DropDown
        UITable             matlab.ui.control.Table
        UIAxes              matlab.ui.control.UIAxes
        Label_2             matlab.ui.control.Label
        Label_3             matlab.ui.control.Label
        spanspan5Label      matlab.ui.control.Label
        Button_3            matlab.ui.control.Button
        spanEditFieldLabel  matlab.ui.control.Label
        spanEditField       matlab.ui.control.NumericEditField
        UIAxes2             matlab.ui.control.UIAxes
        Button_4            matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %%%浏览按键的代码选择
            [filename,pathname]=uigetfile({'*.xlsx';'*.csv'})
            if ~isequal(filename,0)
                app.EditField.Value=fullfile(pathname,filename);
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            %%%%%按钮确定选择的代码模块
            lujin=app.EditField.Value;
            t=readtable(lujin);
            app.UITable.Data=t;
            app.UITable.ColumnName=t.Properties.VariableNames;
            shuju=table2array(app.UITable.Data);
            xl=app.UITable.ColumnName{1};
            yl=app.UITable.ColumnName{2};
            title(app.UIAxes,'平滑处理前数据可视化')
            xlabel(app.UIAxes,xl)
            ylabel(app.UIAxes,yl)
            plot(app.UIAxes,shuju(:,1),shuju(:,2),'r-')
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            cla(app.UIAxes2)
            lujin=app.EditField.Value;
            t=readtable(lujin);
            app.UITable.Data=t;
            app.UITable.ColumnName=t.Properties.VariableNames;
            shuju=table2array(app.UITable.Data);
            xl=app.UITable.ColumnName{1};
            yl=app.UITable.ColumnName{2};
            str=app.DropDown.Value;
            Chuankuan=app.spanEditField.Value;
            Chulihou_shuju=smooth(shuju(:,2),Chuankuan,str)
            plot(app.UIAxes2,shuju(:,1),Chulihou_shuju,'b-')
            title(app.UIAxes2,'平滑处理后数据可视化')
            xlabel(app.UIAxes2,xl)
            ylabel(app.UIAxes2,yl)
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
              %导出excel文件的代码模块
            lujin=app.EditField.Value;
            t=readtable(lujin);
            app.UITable.Data=t;
            app.UITable.ColumnName=t.Properties.VariableNames;
            shuju=table2array(app.UITable.Data);
            str=app.DropDown.Value;
            Chuankuan=app.spanEditField.Value;
            Chulihou_shuju=smooth(shuju(:,2),Chuankuan,str)
            data=[shuju(:,1),Chulihou_shuju];
            filename = '平滑处理后的数据';
            xlswrite(filename,data);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '文件路径';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '路径浏览';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '确定路径';
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = '平滑实现方式选择';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'moving', 'lowess', 'loess', 'sgolay', 'rlowess', 'rloess'};
            app.DropDown.Value = 'moving';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'时间变量'; '待处理的数据'};
            app.UITable.RowName = {};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '数据平滑处理';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = {'注：'; 'moving 移动平均法（默认情况）'; 'lowess 局部回归（加权线性最小二乘和一个一阶多项式模型）'; 'loess 局部回归（加权线性最小二乘和一个二阶多项式模型）'; 'sgolay Sacitzky-Golay滤波，一种广义移动平均滤波法'; 'rlowess lowess方法的稳健形式'; 'rloess loess方法的稳健形式'};
            app.spanspan5Label = uilabel(app.UIFigure);
            app.spanspan5Label.Text = {'注：'; '用span参数指定移动平均滤波器的窗宽，'; '函数内部会强制将span变为奇数。默认为5.'};
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = {'确认参数选择并'; '进行数据平滑化'};
            app.spanEditFieldLabel = uilabel(app.UIFigure);
            app.spanEditFieldLabel.Text = '滤波器窗宽span';
            app.spanEditField = uieditfield(app.UIFigure, 'numeric');
            app.spanEditField.Value = 5;
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '导出数据';
        end
    end

    methods (Access = public)

        % Construct app
        function app = pinghuachuli

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end