classdef shujuchuli < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        APPUIFigure        matlab.ui.Figure
        Menu               matlab.ui.container.Menu
        Menu_4             matlab.ui.container.Menu
        Menu_2             matlab.ui.container.Menu
        Menu_3             matlab.ui.container.Menu
        authorrightMenu    matlab.ui.container.Menu
        TextArea           matlab.ui.control.TextArea
        TabGroup           matlab.ui.container.TabGroup
        Tab                matlab.ui.container.Tab
        Panel              matlab.ui.container.Panel
        UITable            matlab.ui.control.Table
        UIAxes             matlab.ui.control.UIAxes
        Button_2           matlab.ui.control.Button
        Button             matlab.ui.control.Button
        Label              matlab.ui.control.Label
        EditField          matlab.ui.control.EditField
        Panel_3            matlab.ui.container.Panel
        Label_4            matlab.ui.control.Label
        EditField_4        matlab.ui.control.NumericEditField
        Label_5            matlab.ui.control.Label
        EditField_5        matlab.ui.control.NumericEditField
        Label_6            matlab.ui.control.Label
        EditField_6        matlab.ui.control.NumericEditField
        Button_4           matlab.ui.control.Button
        Panel_2            matlab.ui.container.Panel
        Label_2            matlab.ui.control.Label
        EditField_2        matlab.ui.control.NumericEditField
        Button_3           matlab.ui.control.Button
        Label_3            matlab.ui.control.Label
        EditField_3        matlab.ui.control.NumericEditField
        UIAxes2            matlab.ui.control.UIAxes
        Button_5           matlab.ui.control.StateButton
        Tab_2              matlab.ui.container.Tab
        Label_7            matlab.ui.control.Label
        EditField_7        matlab.ui.control.EditField
        Button_6           matlab.ui.control.Button
        Button_7           matlab.ui.control.Button
        UITable2           matlab.ui.control.Table
        UIAxes3            matlab.ui.control.UIAxes
        Buttoncftool       matlab.ui.control.Button
        cftoolLabel        matlab.ui.control.Label
        Label_8            matlab.ui.control.Label
        EditField_8        matlab.ui.control.EditField
        a0a1a2Label        matlab.ui.control.Label
        a0EditFieldLabel   matlab.ui.control.Label
        a0EditField        matlab.ui.control.NumericEditField
        a1EditFieldLabel   matlab.ui.control.Label
        a1EditField        matlab.ui.control.NumericEditField
        a2EditFieldLabel   matlab.ui.control.Label
        a2EditField        matlab.ui.control.NumericEditField
        a4EditFieldLabel   matlab.ui.control.Label
        a4EditField        matlab.ui.control.NumericEditField
        a5Label            matlab.ui.control.Label
        a5EditField        matlab.ui.control.NumericEditField
        a6Label            matlab.ui.control.Label
        a6EditField        matlab.ui.control.NumericEditField
        a3EditFieldLabel   matlab.ui.control.Label
        a3EditField        matlab.ui.control.NumericEditField
        UIAxes4            matlab.ui.control.UIAxes
        Button_8           matlab.ui.control.Button
        Button_9           matlab.ui.control.Button
        wLabel             matlab.ui.control.Label
        wEditField         matlab.ui.control.NumericEditField
        Tab_3              matlab.ui.container.Tab
        Label_9            matlab.ui.control.Label
        EditField_9        matlab.ui.control.EditField
        Button_10          matlab.ui.control.Button
        Button_11          matlab.ui.control.Button
        Button_13          matlab.ui.control.Button
        Label_11           matlab.ui.control.Label
        EditField_13       matlab.ui.control.NumericEditField
        Label_10           matlab.ui.control.Label
        EditField_10Label  matlab.ui.control.Label
        EditField_10       matlab.ui.control.NumericEditField
        EditField_11Label  matlab.ui.control.Label
        EditField_11       matlab.ui.control.NumericEditField
        EditField_12Label  matlab.ui.control.Label
        EditField_12       matlab.ui.control.NumericEditField
        Button_12          matlab.ui.control.Button
        Button_16          matlab.ui.control.Button
        Button_14          matlab.ui.control.Button
        UIAxes5            matlab.ui.control.UIAxes
    end

    %%%%函数文本更新的代码模块
    properties (Access = private)
        StrArray% Description
    end
    
    methods (Access = private)
        
        function Refresh_func(app,StrArrayNew)
            if length(app.StrArray)>=30
                app.StrArray={};
            end
            app.StrArray=[app.StrArray,StrArrayNew];
            app.TextArea.Value=app.StrArray;
        end
        
    end

    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %%%%%模块一按钮确定选择的代码模块
            lujin=app.EditField.Value;
            t=readtable(lujin);
            app.UITable.Data=t;
            app.UITable.ColumnName=t.Properties.VariableNames;
            shuju=table2array(app.UITable.Data);
            xl=app.UITable.ColumnName{1};
            yl=app.UITable.ColumnName{2};
            title(app.UIAxes,'序列可视化')
            xlabel(app.UIAxes,xl)
            ylabel(app.UIAxes,yl)
            plot(app.UIAxes,shuju(:,1),shuju(:,2),'r-')
            StrArrayNew={'massege:表格显示'};
            Refresh_func(app,StrArrayNew);
            StrArrayNew={'massege:图像显示'};
            Refresh_func(app,StrArrayNew);
            
            
        end

        % Menu selected function: authorrightMenu
        function authorrightMenuSelected(app, event)
            msgbox("由华北电力大学(保定) 2019级电气工程及其自动化 沈寒涛完成，华北电力大学(保定) 2020级机械工程系 张文博士指导 ","作者信息","help")
        end

        % Menu selected function: Menu_3
        function Menu_3Selected(app, event)
            msgbox("面向科研，提供傅立叶级数分析、周期分析，拟合分析等数据分析服务，对数据文件（.xslx，.csv）格式要求较高，使用前请看示范视频","简介","help")
        end

        % Menu selected function: Menu_4
        function Menu_4Selected(app, event)
            %%退出
            delete(app.APPUIFigure)
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            %%%模块一浏览按键的代码选择
            [filename,pathname]=uigetfile({'*.csv';'*.xlsx'})
            if ~isequal(filename,0)
                app.EditField.Value=fullfile(pathname,filename);
                StrArrayNew={'massege:Excel 文件浏览完毕'}
                Refresh_func(app,StrArrayNew);
            else
                StrArrayNew={'massege:Excel 文件浏览失败'}
                Refresh_func(app,StrArrayNew);
            end
            
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            %%%傅立叶分析按钮的代码模块
            lujin=app.EditField.Value;
            t=readtable(lujin);
            app.UITable.Data=t;
            app.UITable.ColumnName=t.Properties.VariableNames;
            shuju=table2array(app.UITable.Data);
            Fs = app.EditField_2.Value;            % Sampling frequency
            T = 1/Fs;             % Sampling period
            L = app.EditField_3.Value;             % Length of signal
            t = (0:L-1)*T;        % Time vector
            Y = fft(shuju(:,2));
            P2 = abs(Y/L);%双边谱线
            P1 = P2(1:L/2+1);
            P1(2:end-1) = 2*P1(2:end-1);
            f = Fs*(0:(L/2))/L;
            plot(app.UIAxes2,f,P1)
            title(app.UIAxes2,'单边谱幅值')
            xlabel(app.UIAxes2,'f (Hz)')
            ylabel(app.UIAxes2,'|P1(f)|')
            axis(app.UIAxes2,[0,500,-inf,inf])
            StrArrayNew={'massege:傅立叶分解完成'};
            Refresh_func(app,StrArrayNew);
            
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            %%%数据其他特征分析代码模块
            lujin=app.EditField.Value;
            t=readtable(lujin);
            app.UITable.Data=t;
            app.UITable.ColumnName=t.Properties.VariableNames;
            shuju=table2array(app.UITable.Data);
            Fs = app.EditField_2.Value;            % Sampling frequency
            T = 1/Fs;             % Sampling period
            L = app.EditField_3.Value;             % Length of signal
            t = (0:L-1)*T;        % Time vector
            Y = fft(shuju(:,2));
            P2 = abs(Y/L);%双边谱线
            P1 = P2(1:L/2+1);
            P1(2:end-1) = 2*P1(2:end-1);
            f = Fs*(0:(L/2))/L;
            [b,i]=sort(P1);
            mubiao=i(length(P1));
            if mubiao==1
                mubiao=i(length(P1)-1);
            end
            zhouqi=1/f(mubiao);
            app.EditField_4.Value=zhouqi;
            app.EditField_5.Value=max(shuju(:,2));
            app.EditField_6.Value=min(shuju(:,2));
            StrArrayNew={'massege:数据特征分析完成'};
            Refresh_func(app,StrArrayNew);
        end

        % Value changed function: Button_5
        function Button_5ValueChanged(app, event)
            %%%导出设置
            value = app.Button_5.Value;
            lujin=app.EditField.Value;
            t=readtable(lujin);
            app.UITable.Data=t;
            app.UITable.ColumnName=t.Properties.VariableNames;
            shuju=table2array(app.UITable.Data);
            Fs = app.EditField_2.Value;            % Sampling frequency
            T = 1/Fs;             % Sampling period
            L = app.EditField_3.Value;             % Length of signal
            t = (0:L-1)*T;        % Time vector
            Y = fft(shuju(:,2));
            P2 = abs(Y/L);%双边谱线
            P1 = P2(1:L/2+1);
            P1(2:end-1) = 2*P1(2:end-1);
            f = Fs*(0:(L/2))/L;
            figure
            plot(f,P1)
            title('单边谱幅值')
            xlabel('f (Hz)')
            ylabel('|P1(f)|')
            axis([0,500,-inf,inf])
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            %%模块二中浏览
            [filename,pathname]=uigetfile({'*.csv';'*.xlsx'})
            if ~isequal(filename,0)
                app.EditField_7.Value=fullfile(pathname,filename);
                StrArrayNew={'massege:Excel 文件浏览完毕'}
                Refresh_func(app,StrArrayNew);
            else
                StrArrayNew={'massege:Excel 文件浏览失败'}
                Refresh_func(app,StrArrayNew);
            end
            
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            %%%%%模块二按钮确定选择的代码模块
            lujin=app.EditField_7.Value;
            t=readtable(lujin);
            app.UITable2.Data=t;
            app.UITable2.ColumnName=t.Properties.VariableNames;
            shuju=table2array(app.UITable2.Data);
            xl=app.UITable2.ColumnName{1};
            yl=app.UITable2.ColumnName{2};
            title(app.UIAxes3,'序列可视化')
            xlabel(app.UIAxes3,xl)
            ylabel(app.UIAxes3,yl)
            plot(app.UIAxes3,shuju(:,1),shuju(:,2),'r-')
            StrArrayNew={'massege:表格显示'};
            Refresh_func(app,StrArrayNew);
            StrArrayNew={'massege:图像显示'};
            Refresh_func(app,StrArrayNew);
            %%%%%作出散点图
            title(app.UIAxes4,'拟合效果示意图')
            xlabel(app.UIAxes4,xl)
            ylabel(app.UIAxes4,yl)
            plot(app.UIAxes4,shuju(:,1),shuju(:,2),'b*')
            hold(app.UIAxes4,"on")
            StrArrayNew={'massege:图像散点图显示'};
            Refresh_func(app,StrArrayNew);
            
        end

        % Button pushed function: Buttoncftool
        function ButtoncftoolPushed(app, event)
            %%%创建工作区变量，并打开cftool
            lujin=app.EditField_7.Value;
            t=readtable(lujin);
            app.UITable2.Data=t;
            app.UITable2.ColumnName=t.Properties.VariableNames;
            shuju=table2array(app.UITable2.Data);
            diyibianliang=shuju(:,1);
            dierbianliang=shuju(:,2);
            assignin('base','x',diyibianliang);
            assignin('base','y',dierbianliang);
            StrArrayNew={'massege:工作区变量已建立完毕'};
            Refresh_func(app,StrArrayNew);
            cftool
            StrArrayNew={'massege:cftool打开成功'};
            Refresh_func(app,StrArrayNew);
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            lujin=app.EditField_7.Value;
            t=readtable(lujin);
            app.UITable2.Data=t;
            app.UITable2.ColumnName=t.Properties.VariableNames;
            shuju=table2array(app.UITable2.Data);
            diyibianliang=shuju(:,1);
            dierbianliang=shuju(:,2);
            a0=app.a0EditField.Value;
            a1=app.a1EditField.Value;
            a2=app.a2EditField.Value;
            a3=app.a3EditField.Value;
            a4=app.a4EditField.Value;
            a5=app.a5EditField.Value;
            a6=app.a6EditField.Value;
            w =app.wEditField.Value;
            x=diyibianliang;
            nihejieguo=a0+a1*sin(w*x)+a2*cos(w*x)+a3*sin(2*w*x)+a4*cos(2*w*x)+a5*sin(3*w*x)+a6*cos(3*w*x);
            plot(app.UIAxes4,x,nihejieguo,'r-','LineWidth',2)
            
            
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            %%模块二导出
            lujin=app.EditField_7.Value;
            t=readtable(lujin);
            app.UITable2.Data=t;
            app.UITable2.ColumnName=t.Properties.VariableNames;
            shuju=table2array(app.UITable2.Data);
            xl=app.UITable2.ColumnName{1};
            yl=app.UITable2.ColumnName{2};
            diyibianliang=shuju(:,1);
            dierbianliang=shuju(:,2);
            figure
            title('拟合效果示意图')
            xlabel(xl)
            ylabel(yl)
            plot(shuju(:,1),shuju(:,2),'b*')
            hold on
            a0=app.a0EditField.Value;
            a1=app.a1EditField.Value;
            a2=app.a2EditField.Value;
            a3=app.a3EditField.Value;
            a4=app.a4EditField.Value;
            a5=app.a5EditField.Value;
            a6=app.a6EditField.Value;
            w =app.wEditField.Value;
            x=diyibianliang;
            nihejieguo=a0+a1*sin(w*x)+a2*cos(w*x)+a3*sin(2*w*x)+a4*cos(2*w*x)+a5*sin(3*w*x)+a6*cos(3*w*x);
            plot(x,nihejieguo,'r-','LineWidth',2)
            legend("原数据点","拟合曲线")
            hold off
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            %%%模块一浏览按键的代码选择
            [filename,pathname]=uigetfile({'*.csv';'*.xlsx'})
            if ~isequal(filename,0)
                app.EditField_9.Value=fullfile(pathname,filename);
                StrArrayNew={'massege:Excel 文件浏览完毕'}
                Refresh_func(app,StrArrayNew);
            else
                StrArrayNew={'massege:Excel 文件浏览失败'}
                Refresh_func(app,StrArrayNew);
            end
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            %%%%%模块三按钮确定选择的代码模块
            lujin=app.EditField_9.Value;
            t=readtable(lujin);
            %             app.UITable.ColumnName=t.Properties.VariableNames;
            shuju=table2array(t);
            xl=t.Properties.VariableNames{1};
            yl=t.Properties.VariableNames{2};
            title(app.UIAxes5,'不同工况下的数据比较')
            xlabel(app.UIAxes5,xl)
            ylabel(app.UIAxes5,yl)
            plot(app.UIAxes5,shuju(:,1),shuju(:,2),'r-','LineWidth',2)
            xticks(app.UIAxes5,0:60:180)
            hold(app.UIAxes5,"on")
            ymax=1.25*max(shuju(:,2));
            plot(app.UIAxes5,[60,60],[-ymax,ymax],'k--')
            plot(app.UIAxes5,[120,120],[-ymax,ymax],'k--')
            axis(app.UIAxes5,[0,180,-ymax,ymax])
            hold(app.UIAxes5,"off")
            StrArrayNew={'massege:图像显示'};
            Refresh_func(app,StrArrayNew);
            
            
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            
            %%%%%体现方式一代码模块
            cla(app.UIAxes5)
            lujin=app.EditField_9.Value;
            t=readtable(lujin);
            %             app.UITable.ColumnName=t.Properties.VariableNames;
            shuju=table2array(t);
            xl=t.Properties.VariableNames{1};
            yl=t.Properties.VariableNames{2};
            title(app.UIAxes5,'不同工况下的数据比较')
            xlabel(app.UIAxes5,xl)
            ylabel(app.UIAxes5,yl)
            plot(app.UIAxes5,shuju(:,1),shuju(:,2),'r-','LineWidth',2)
            xticks(app.UIAxes5,0:60:180)
            hold(app.UIAxes5,"on")
            ymax=1.25*max(shuju(:,2));
            plot(app.UIAxes5,[60,60],[-ymax,ymax],'k--')
            plot(app.UIAxes5,[120,120],[-ymax,ymax],'k--')
            axis(app.UIAxes5,[0,180,-ymax,ymax])
            h1=roundn(app.EditField_10.Value,-2);
            h2=roundn(app.EditField_11.Value,-2);
            h3=roundn(app.EditField_12.Value,-2);
            plot(app.UIAxes5,[0,60],[h1,h1],'g--','LineWidth',2)
            plot(app.UIAxes5,[60,120],[h2,h2],'b--','LineWidth',2)
            plot(app.UIAxes5,[120,180],[h3,h3],'m--','LineWidth',2)
            s1=num2str(h1);
            s2=num2str(h2);
            s3=num2str(h3);
            lujin=app.EditField_9.Value;
            t=readtable(lujin);
            shuju=table2array(t);
            caiyangpl=app.EditField_13.Value;
            L=180*caiyangpl/1000;
            m1=max(shuju(1:L/3,2))*1.25;
            m2=max(shuju(L/3:L/3*2,2))*1.2;
            m3=max(shuju(2*L/3:L,2))*1.15;
            text(app.UIAxes5,20,m1,s1);
            text(app.UIAxes5,80,m2,s2);
            text(app.UIAxes5,140,m3,s3);
            StrArrayNew={'massege:已将均方根值体现在图中'};
            Refresh_func(app,StrArrayNew);
            %             annotation(app.UIAxes5,'arrow',[20,15],[m1,h1])
            %             annotation(app.UIAxes5,'arrow',[80,75],[m2,h2])
            %             annotation(app.UIAxes5,'arrow',[140,135],[m3,h3])
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            lujin=app.EditField_9.Value;
            t=readtable(lujin);
            shuju=table2array(t);
            caiyangpl=app.EditField_13.Value;
            L=180*caiyangpl/1000;
            a1=shuju(1:L/3,2);
            s1=sum(a1.^2);
            L1=length(a1);
            app.EditField_10.Value=sqrt(s1/L1);
            
            a2=shuju(L/3:L/3*2,2);
            s2=sum(a2.^2);
            L2=length(a2);
            app.EditField_11.Value=sqrt(s2/L2);
            
            a3=shuju(2*L/3:L,2);
            s3=sum(a3.^2);
            L3=length(a3);
            app.EditField_12.Value=sqrt(s3/L3);
            StrArrayNew={'massege:均方根值计算完成'};
            Refresh_func(app,StrArrayNew);
        end

        % Callback function
        function Button_14Pushed(app, event)
            %%按键已删除
        end

        % Button pushed function: Button_14
        function Button_14Pushed2(app, event)
            cla(app.UIAxes5)
            StrArrayNew={'massege:图像已清除'};
            Refresh_func(app,StrArrayNew);
        end

        % Button pushed function: Button_16
        function Button_16Pushed(app, event)
            %%%%%体现方式一代码模块
            figure
            lujin=app.EditField_9.Value;
            t=readtable(lujin);
            %             app.UITable.ColumnName=t.Properties.VariableNames;
            shuju=table2array(t);
            xl=t.Properties.VariableNames{1};
            yl=t.Properties.VariableNames{2};
            title('不同工况下的数据比较')
            xlabel(xl)
            ylabel(yl)
            plot(shuju(:,1),shuju(:,2),'r-','LineWidth',2)
            xticks(0:60:180)
            hold on
            ymax=1.25*max(shuju(:,2));
            plot([60,60],[-ymax,ymax],'k--')
            plot([120,120],[-ymax,ymax],'k--')
            axis([0,180,-ymax,ymax])
            h1=roundn(app.EditField_10.Value,-2);
            h2=roundn(app.EditField_11.Value,-2);
            h3=roundn(app.EditField_12.Value,-2);
            plot([0,60],[h1,h1],'g--','LineWidth',2)
            plot([60,120],[h2,h2],'b--','LineWidth',2)
            plot([120,180],[h3,h3],'m--','LineWidth',2)
            s1=num2str(h1);
            s2=num2str(h2);
            s3=num2str(h3);
            lujin=app.EditField_9.Value;
            t=readtable(lujin);
            shuju=table2array(t);
            caiyangpl=app.EditField_13.Value;
            L=180*caiyangpl/1000;
            m1=max(shuju(1:L/3,2))*1.25;
            m2=max(shuju(L/3:L/3*2,2))*1.2;
            m3=max(shuju(2*L/3:L,2))*1.15;
            text(20,m1,s1);
            text(80,m2,s2);
            text(140,m3,s3);
            StrArrayNew={'massege:图像导出完毕'};
            Refresh_func(app,StrArrayNew);
            %             quiver(20,m1,20,h1,'k','filled','LineWidth',2)
            %             quiver(80,m2,20,h2,'k','filled','LineWidth',2)
            %             quiver(140,m3,20,h3,'k','filled','LineWidth',2)
            %             annotation('arrow',[20,15],[m1,h1])
            %             annotation('arrow',[80,75],[m2,h2])
            %             annotation('arrow',[140,135],[m3,h3])
            hold off
        end

        % Callback function
        function Button_15Pushed(app, event)
            %%按键已删除
        end

        % Callback function
        function Button_17Pushed(app, event)
            %%按键已删除
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.APPUIFigure = uifigure;
            app.APPUIFigure.Name = '数据分析APP';
            app.Menu = uimenu(app.APPUIFigure);
            app.Menu.Text = 'Menu';
            app.Menu_4 = uimenu(app.Menu);
            app.Menu_4.MenuSelectedFcn = createCallbackFcn(app, @Menu_4Selected, true);
            app.Menu_4.Text = '退出';
            app.Menu_2 = uimenu(app.APPUIFigure);
            app.Menu_2.Text = '帮助';
            app.Menu_3 = uimenu(app.Menu_2);
            app.Menu_3.MenuSelectedFcn = createCallbackFcn(app, @Menu_3Selected, true);
            app.Menu_3.Text = '功能介绍';
            app.authorrightMenu = uimenu(app.Menu_2);
            app.authorrightMenu.MenuSelectedFcn = createCallbackFcn(app, @authorrightMenuSelected, true);
            app.authorrightMenu.Text = 'author right';
            app.TextArea = uitextarea(app.APPUIFigure);
            app.TabGroup = uitabgroup(app.APPUIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '模块一：数据特征分析';
            app.Panel = uipanel(app.Tab);
            app.Panel.Title = '数据选择和显示';
            app.UITable = uitable(app.Panel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'};
            app.UITable.RowName = {};
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Button_2 = uibutton(app.Panel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '浏览路径';
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '确定路径';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '文件路径选择';
            app.EditField = uieditfield(app.Panel, 'text');
            app.Panel_3 = uipanel(app.Tab);
            app.Panel_3.Title = '其他特征';
            app.Label_4 = uilabel(app.Panel_3);
            app.Label_4.Text = '周期';
            app.EditField_4 = uieditfield(app.Panel_3, 'numeric');
            app.Label_5 = uilabel(app.Panel_3);
            app.Label_5.Text = '极大值';
            app.EditField_5 = uieditfield(app.Panel_3, 'numeric');
            app.Label_6 = uilabel(app.Panel_3);
            app.Label_6.Text = '极小值';
            app.EditField_6 = uieditfield(app.Panel_3, 'numeric');
            app.Button_4 = uibutton(app.Panel_3, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '分析';
            app.Panel_2 = uipanel(app.Tab);
            app.Panel_2.Title = '傅立叶分解';
            app.Label_2 = uilabel(app.Panel_2);
            app.Label_2.Text = '采样频率';
            app.EditField_2 = uieditfield(app.Panel_2, 'numeric');
            app.Button_3 = uibutton(app.Panel_2, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '傅立叶分解';
            app.Label_3 = uilabel(app.Panel_2);
            app.Label_3.Text = '采样序列长度';
            app.EditField_3 = uieditfield(app.Panel_2, 'numeric');
            app.UIAxes2 = uiaxes(app.Panel_2);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.Button_5 = uibutton(app.Panel_2, 'state');
            app.Button_5.ValueChangedFcn = createCallbackFcn(app, @Button_5ValueChanged, true);
            app.Button_5.Text = '导出图像';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '模块二：数据二维傅立叶拟合分析';
            app.Label_7 = uilabel(app.Tab_2);
            app.Label_7.Text = '数据路径';
            app.EditField_7 = uieditfield(app.Tab_2, 'text');
            app.Button_6 = uibutton(app.Tab_2, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '浏览';
            app.Button_7 = uibutton(app.Tab_2, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '确定选择';
            app.UITable2 = uitable(app.Tab_2);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'};
            app.UITable2.RowName = {};
            app.UIAxes3 = uiaxes(app.Tab_2);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.Buttoncftool = uibutton(app.Tab_2, 'push');
            app.Buttoncftool.ButtonPushedFcn = createCallbackFcn(app, @ButtoncftoolPushed, true);
            app.Buttoncftool.Text = 'Button：在工作区中创建变量并打开cftool工具箱';
            app.cftoolLabel = uilabel(app.Tab_2);
            app.cftoolLabel.Text = '使用说明：复制文本框中的拟合函数类型后，点击调用cftool按钮，进行自定义拟';
            app.Label_8 = uilabel(app.Tab_2);
            app.Label_8.Text = '拟合函数类型';
            app.EditField_8 = uieditfield(app.Tab_2, 'text');
            app.EditField_8.Value = 'a0+a1*sin(w*x)+a2*cos(w*x)+a3*sin(2*w*x)+a4*cos(2*w*x)+a5*sin(3*w*x)+a6*cos(3*w*x)';
            app.a0a1a2Label = uilabel(app.Tab_2);
            app.a0a1a2Label.Text = '合后再将拟合结果，即a0,a1,a2,······等值填入对应文本框中。点击确认后进行操作。';
            app.a0EditFieldLabel = uilabel(app.Tab_2);
            app.a0EditFieldLabel.Text = 'a0';
            app.a0EditField = uieditfield(app.Tab_2, 'numeric');
            app.a1EditFieldLabel = uilabel(app.Tab_2);
            app.a1EditFieldLabel.Text = 'a1';
            app.a1EditField = uieditfield(app.Tab_2, 'numeric');
            app.a2EditFieldLabel = uilabel(app.Tab_2);
            app.a2EditFieldLabel.Text = 'a2';
            app.a2EditField = uieditfield(app.Tab_2, 'numeric');
            app.a4EditFieldLabel = uilabel(app.Tab_2);
            app.a4EditFieldLabel.Text = 'a4';
            app.a4EditField = uieditfield(app.Tab_2, 'numeric');
            app.a5Label = uilabel(app.Tab_2);
            app.a5Label.Text = 'a5 ';
            app.a5EditField = uieditfield(app.Tab_2, 'numeric');
            app.a6Label = uilabel(app.Tab_2);
            app.a6Label.Text = 'a6 ';
            app.a6EditField = uieditfield(app.Tab_2, 'numeric');
            app.a3EditFieldLabel = uilabel(app.Tab_2);
            app.a3EditFieldLabel.Text = 'a3';
            app.a3EditField = uieditfield(app.Tab_2, 'numeric');
            app.UIAxes4 = uiaxes(app.Tab_2);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.Button_8 = uibutton(app.Tab_2, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '确认';
            app.Button_9 = uibutton(app.Tab_2, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '导出';
            app.wLabel = uilabel(app.Tab_2);
            app.wLabel.Text = 'w ';
            app.wEditField = uieditfield(app.Tab_2, 'numeric');
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '模块三：图形绘制';
            app.Label_9 = uilabel(app.Tab_3);
            app.Label_9.Text = '文件路径';
            app.EditField_9 = uieditfield(app.Tab_3, 'text');
            app.Button_10 = uibutton(app.Tab_3, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '浏览';
            app.Button_11 = uibutton(app.Tab_3, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '应用路径';
            app.Button_13 = uibutton(app.Tab_3, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Text = '计算分段均方根值';
            app.Label_11 = uilabel(app.Tab_3);
            app.Label_11.Text = '请输入采样频率';
            app.EditField_13 = uieditfield(app.Tab_3, 'numeric');
            app.Label_10 = uilabel(app.Tab_3);
            app.Label_10.Text = '计算得：';
            app.EditField_10Label = uilabel(app.Tab_3);
            app.EditField_10Label.Text = '均方根值一';
            app.EditField_10 = uieditfield(app.Tab_3, 'numeric');
            app.EditField_10.Editable = 'off';
            app.EditField_11Label = uilabel(app.Tab_3);
            app.EditField_11Label.Text = '均方根值二';
            app.EditField_11 = uieditfield(app.Tab_3, 'numeric');
            app.EditField_11.Editable = 'off';
            app.EditField_12Label = uilabel(app.Tab_3);
            app.EditField_12Label.Text = '均方根值三';
            app.EditField_12 = uieditfield(app.Tab_3, 'numeric');
            app.EditField_12.Editable = 'off';
            app.Button_12 = uibutton(app.Tab_3, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '体现均方根值';
            app.Button_16 = uibutton(app.Tab_3, 'push');
            app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @Button_16Pushed, true);
            app.Button_16.Text = '导出图像';
            app.Button_14 = uibutton(app.Tab_3, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed2, true);
            app.Button_14.Text = '清空图像';
            app.UIAxes5 = uiaxes(app.Tab_3);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = shujuchuli

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.APPUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.APPUIFigure)
        end
    end
end